classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure        matlab.ui.Figure
        PlayerButton_2  matlab.ui.control.Button
        Image3          matlab.ui.control.Image
        Image2_2        matlab.ui.control.Image
        Image_2         matlab.ui.control.Image
        Image2          matlab.ui.control.Image
        Image           matlab.ui.control.Image
        PlayerButton    matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: PlayerButton
        function SinglePlayer(app, event)
            app.PlayerButton.Visible = 'off';
            app.PlayerButton_2.Visible = 'off';
            app.Image.Visible = 'off';
            app.Image_2.Visible = 'off';
            app.Image2.Visible = 'off';
            app.Image2_2.Visible = 'off';
            app.Image3.Visible = 'off';
            
        end

        % Button pushed function: PlayerButton_2
        function Multiplayer(app, event)
            app.PlayerButton.Visible = 'off';
            app.PlayerButton_2.Visible = 'off';
            app.Image.Visible = 'off';
            app.Image_2.Visible = 'off';
            app.Image2.Visible = 'off';
            app.Image2_2.Visible = 'off';
            app.Image3.Visible = 'off';

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.PlayerButton = uibutton(app.UIFigure, 'push');
            app.PlayerButton.ButtonPushedFcn = createCallbackFcn(app, @SinglePlayer, true);
            app.PlayerButton.Text = '1 Player';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = '1187eb9cfb72e78e84d87f283efc2f30.png';
            app.Image2 = uiimage(app.UIFigure);
            app.Image2.ImageSource = 'flip.png';
            app.Image_2 = uiimage(app.UIFigure);
            app.Image_2.ImageSource = '1187eb9cfb72e78e84d87f283efc2f30.png';
            app.Image2_2 = uiimage(app.UIFigure);
            app.Image2_2.ImageSource = 'flip.png';
            app.Image3 = uiimage(app.UIFigure);
            app.Image3.ImageSource = 'Untitled drawing.png';
            app.PlayerButton_2 = uibutton(app.UIFigure, 'push');
            app.PlayerButton_2.ButtonPushedFcn = createCallbackFcn(app, @Multiplayer, true);
            app.PlayerButton_2.Text = '2 Player';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end